classdef PillSense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MessageWindowLabel         matlab.ui.control.Label
        ConnectButton              matlab.ui.control.Button
        PillSense3DSimulatorLabel  matlab.ui.control.Label
        DisconnectButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function startupFcn(app)
            % Show welcome image (ensure it's in the same folder or use full path)
            welcomeImg = imread('screen.png');  % Replace with your image name
            image(app.UIAxes, welcomeImg);
            axis(app.UIAxes, 'off');             % Hide axes ticks
            %app.WelcomeLabel.Visible = 'on';     % Show welcome text
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            %[file, path] = uigetfile({'*.mp4;*.avi','Video Files (*.mp4, *.avi)'});
            %if isequal(file,0)
            %    return; % user canceled
            %end
            %videoPath = fullfile('C:\Users\Faisal\Downloads\RTS_Project\2025-05-04 15-49-31.mp4');
            
            % Read video
            %welcomeImg = imread('screen.png');
            %image(app.UIAxes, welcomeImg);
            %axis(app.UIAxes, 'off');             % Hide axes ticks
            %app.WelcomeLabel.Visible = 'on';     % Show welcome text
            vidObj = VideoReader('2025-05-04 15-49-31.mp4');
            
            % Play video
            while hasFrame(vidObj)
                frame = readFrame(vidObj);
                image(app.UIAxes, frame);
                app.UIAxes.Visible = 'off';
                pause(1/vidObj.FrameRate);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            welcomeImg = imread('screen.png');  % Replace with your image name
            image(app.UIAxes, welcomeImg);
            axis(app.UIAxes, 'off');             % Hide axes ticks
            %app.WelcomeLabel.Visible = 'on';     % Show welcome text
            mqClient = mqttclient("tcp://broker.hivemq.com",ClientID="myClient",Port=1883);

            % Subscribe to the topic
            subscribe(mqClient, "test/wokwi/matlab");
            
            
            % Read data in a loop
            
            videos = ["PillSense v1.avi" "PillSense v2.avi" "PillSense v3.avi"];
            app.UIAxes.Title.String = '';
            while true
                app.MessageWindowLabel.Text = "Listening for messages...";
                data = read(mqClient, Topic = "test/wokwi/matlab");
                if ~isempty(data)
                    app.MessageWindowLabel.Text = "Received: " + string(data.Data);
                    if strcmp(data.Data, "Type - 1") || strcmp(data.Data, "Type - 1 Setup")
                        vidObj = VideoReader(videos(1));
                    elseif strcmp(data.Data, "Type - 2") || strcmp(data.Data, "Type - 2 Setup")
                        vidObj = VideoReader(videos(2));
                    elseif strcmp(data.Data, "Type - 3") || strcmp(data.Data, "Type - 3 Setup")
                        vidObj = VideoReader(videos(3));
                    end
            
                    % Play video
                    while hasFrame(vidObj)
                        frame = readFrame(vidObj);
                        image(app.UIAxes, frame);
                        app.UIAxes.Visible = 'off';
                        pause(1/vidObj.FrameRate);
                    end
                    pause(5);
                    image(app.UIAxes, welcomeImg);
                end
                pause(1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PillSense3DSimulatorLabel = uilabel(app.UIFigure);
            app.PillSense3DSimulatorLabel.Text = '  PillSense  - 3D Simulator';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.MessageWindowLabel = uilabel(app.UIFigure);
            app.MessageWindowLabel.Text = 'Message Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PillSense

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end